classdef design_figure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        design_figureUIFigure     matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        FigTab                    matlab.ui.container.Tab
        FigurepropertiesPanel     matlab.ui.container.Panel
        InsertFigname             matlab.ui.control.EditField
        BackgroundgridCheckBox    matlab.ui.control.CheckBox
        LoadfigurePanel           matlab.ui.container.Panel
        loadmButton               matlab.ui.control.Button
        loadgfcButton             matlab.ui.control.Button
        loadfigButton             matlab.ui.control.Button
        SavefigurePanel           matlab.ui.container.Panel
        savemButton               matlab.ui.control.Button
        savefigButton             matlab.ui.control.Button
        ExportfigurePanel         matlab.ui.container.Panel
        ResolutionEditFieldLabel  matlab.ui.control.Label
        ResolutionEditField       matlab.ui.control.NumericEditField
        jpgButton                 matlab.ui.control.Button
        tiffButton                matlab.ui.control.Button
        pdfButton                 matlab.ui.control.Button
        epsButton                 matlab.ui.control.Button
        svgButton                 matlab.ui.control.Button
        CloseFigureButton         matlab.ui.control.Button
        CreatefigurePanel         matlab.ui.container.Panel
        FigSizeDropDown           matlab.ui.control.DropDown
        CreateFigButton           matlab.ui.control.Button
        CustomsizePanel           matlab.ui.container.Panel
        UnitsButtonGroup          matlab.ui.container.ButtonGroup
        CentimetersButton         matlab.ui.control.RadioButton
        NormalizedButton          matlab.ui.control.RadioButton
        InchesButton              matlab.ui.control.RadioButton
        CreateCustomFigButton     matlab.ui.control.Button
        xEditField_2Label         matlab.ui.control.Label
        x_fig                     matlab.ui.control.NumericEditField
        yEditField_2Label         matlab.ui.control.Label
        y_fig                     matlab.ui.control.NumericEditField
        wEditField_2Label         matlab.ui.control.Label
        w_fig                     matlab.ui.control.NumericEditField
        hEditField_2Label         matlab.ui.control.Label
        h_fig                     matlab.ui.control.NumericEditField
        EditFigureButton          matlab.ui.control.Button
        UpdateFigureButton        matlab.ui.control.Button
        helpCreateFig             matlab.ui.control.Button
        helpCustomFig             matlab.ui.control.Button
        helpFigProperties         matlab.ui.control.Button
        helpLoadFig               matlab.ui.control.Button
        helpSaveFig               matlab.ui.control.Button
        helpExportFig             matlab.ui.control.Button
        AxesTab                   matlab.ui.container.Tab
        AddaxesPanel              matlab.ui.container.Panel
        xEditFieldLabel           matlab.ui.control.Label
        x_axes                    matlab.ui.control.NumericEditField
        yEditFieldLabel           matlab.ui.control.Label
        y_axes                    matlab.ui.control.NumericEditField
        wEditFieldLabel           matlab.ui.control.Label
        w_axes                    matlab.ui.control.NumericEditField
        hEditFieldLabel           matlab.ui.control.Label
        h_axes                    matlab.ui.control.NumericEditField
        AddAxesButton             matlab.ui.control.Button
        EditAxesButton            matlab.ui.control.Button
        DeleteAxesButton          matlab.ui.control.Button
        UpdateAxesButton          matlab.ui.control.Button
        ListBoxLabel              matlab.ui.control.Label
        AxesListBox               matlab.ui.control.ListBox
        moveAxUpListButton        matlab.ui.control.Button
        moveAxDownListButton      matlab.ui.control.Button
        WorkspaceButton           matlab.ui.control.Button
        AddaxesgridPanel          matlab.ui.container.Panel
        AreaLabel                 matlab.ui.control.Label
        GridAreaButtonGroup       matlab.ui.container.ButtonGroup
        FullAreaButton            matlab.ui.control.RadioButton
        CustomAreaButton          matlab.ui.control.RadioButton
        GapxEditFieldLabel        matlab.ui.control.Label
        GapxEditField             matlab.ui.control.NumericEditField
        GapyEditFieldLabel        matlab.ui.control.Label
        GapyEditField             matlab.ui.control.NumericEditField
        ncolEditFieldLabel        matlab.ui.control.Label
        ncolEditField             matlab.ui.control.NumericEditField
        nrowEditFieldLabel        matlab.ui.control.Label
        nrowEditField             matlab.ui.control.NumericEditField
        AddAxesGridButton         matlab.ui.control.Button
        DuplicateaxesPanel        matlab.ui.container.Panel
        GapEditFieldLabel         matlab.ui.control.Label
        GapEditField              matlab.ui.control.NumericEditField
        DuplicateLeftButton       matlab.ui.control.Button
        DuplicateRightButton      matlab.ui.control.Button
        DuplicateUpButton         matlab.ui.control.Button
        DuplicateDownButton       matlab.ui.control.Button
        cmLabel                   matlab.ui.control.Label
        MoveaxesPanel             matlab.ui.container.Panel
        DistEditFieldLabel        matlab.ui.control.Label
        DistEditField             matlab.ui.control.NumericEditField
        MoveLeftButton            matlab.ui.control.Button
        MoveRightButton           matlab.ui.control.Button
        MoveUpButton              matlab.ui.control.Button
        MoveDownButton            matlab.ui.control.Button
        cmLabel_2                 matlab.ui.control.Label
        AlignselectedaxesPanel    matlab.ui.container.Panel
        AlignLeftLButton          matlab.ui.control.Button
        AlignRightLButton         matlab.ui.control.Button
        AlignTopUButton           matlab.ui.control.Button
        AlignBottomUButton        matlab.ui.control.Button
        AlignLeftRButton          matlab.ui.control.Button
        AlignRightRButton         matlab.ui.control.Button
        AlignTopBButton           matlab.ui.control.Button
        AlignBottomBButton        matlab.ui.control.Button
        ScaleselectedaxesPanel    matlab.ui.container.Panel
        WupButton                 matlab.ui.control.Button
        HupButton                 matlab.ui.control.Button
        WidthHeightupButton       matlab.ui.control.Button
        WdownButton               matlab.ui.control.Button
        HdownButton               matlab.ui.control.Button
        WidthHeightdownButton     matlab.ui.control.Button
        RotateselectedaxesPanel   matlab.ui.container.Panel
        RotateAxesEditField       matlab.ui.control.NumericEditField
        degreesLabel              matlab.ui.control.Label
        TextTab                   matlab.ui.container.Tab
        AddaxeslabelsPanel        matlab.ui.container.Panel
        LabelEditField            matlab.ui.control.EditField
        xEditFieldLabel_2         matlab.ui.control.Label
        x_label                   matlab.ui.control.NumericEditField
        yEditFieldLabel_2         matlab.ui.control.Label
        y_label                   matlab.ui.control.NumericEditField
        LabelEditFieldLabel       matlab.ui.control.Label
        FontDropDownLabel         matlab.ui.control.Label
        FontDropDown              matlab.ui.control.DropDown
        BoldCheckBox              matlab.ui.control.CheckBox
        BoldLabel                 matlab.ui.control.Label
        AddLabelButton            matlab.ui.control.Button
        EditLabelButton           matlab.ui.control.Button
        DeleteLabelButton         matlab.ui.control.Button
        UpdateLabelButton         matlab.ui.control.Button
        moveLabelUpListButton     matlab.ui.control.Button
        moveLabelDownListButton   matlab.ui.control.Button
        AutoAddLabelsButton       matlab.ui.control.Button
        ListBoxLabel_2            matlab.ui.control.Label
        LabelListBox              matlab.ui.control.ListBox
        ItalicsCheckBox           matlab.ui.control.CheckBox
        ItLabel                   matlab.ui.control.Label
    end

 
    %% created in MATLAB2020b
    %% Soraya Dunn 2020
    
    methods (Access = private)
        
        function create_figure(app)
            figsize = app.FigSizeDropDown.Value;
            
            switch figsize
                case 'Size'
                    msgbox('Select size for figure','Warning');
                    return
                case 'A4_vertical'
                    fig = figure('units','centimeters','position',[10 0 21.0 29.7]);
                case 'A4_horizontal'
                    fig = figure('units','centimeters','position',[10 1 29.7 21.0]);
                case 'optimal_laptop'
                    fig = figure('units','centimeters','position',[5.5 1.5 36.5 20]);
                case 'fullscreen'
                    fig = figure('units','normalized','position',[0 0 1 1]);
                case 'halfscreen'
                    fig = figure('units','normalized','position',[0.3 0 0.5 1]);
                case 'optimal_monitor'
                    fig = figure('units','centimeters','position',[5.4 0.2 43.2 26]);
                case 'paper_figure'
                    fig = figure('units','centimeters','position',[5 1 17.4 24.7]);
            end
            
            fig.Name = app.InsertFigname.Value;
            
            set(fig,'Units','centimeters')
            updateGUI_fig_position(app)
            [~] = add_background_grid(app,[]);
            
        end
        
        function create_custom_figure(app)
            figunits = app.UnitsButtonGroup.SelectedObject.Text;
            
            x = app.x_fig.Value;
            y = app.y_fig.Value;
            w = app.w_fig.Value;
            h = app.h_fig.Value;
            
            figure('units',figunits,'position',[x y w h])
            set(gcf,'Units','centimeters')
            updateGUI_fig_position(app)
            [~] = add_background_grid(app,[]);
        end
        
        function update_figure(app)
            figunits = app.UnitsButtonGroup.SelectedObject.Text;
            
            x = app.x_fig.Value;
            y = app.y_fig.Value;
            w = app.w_fig.Value;
            h = app.h_fig.Value;
            
            set(gcf,'units',figunits,'position',[x y w h])
            set(gcf,'Units','centimeters')
        end
        
        
        function bgax = add_background_grid(app,bgax)
            if isempty(bgax)
                bgax = axes('next','add','position',[0 0 1 1],'color',[0.95 0.95 0.95],'tag','bgax');
            end
            
            figpos = get(gcf,'position');
            
            set(bgax,'xlim',[0 figpos(3)],'xtick',0:1:figpos(3),'ylim',[0 figpos(4)],'ytick',0:1:figpos(4),'xgrid','on','ygrid','on');
            
            for n = 1:figpos(3)
                text(bgax,n-0.2,0.2,num2str(n),'FontSize',8,'Tag','bgaxGrid')
            end
            for k = 1:figpos(4)
                text(bgax,0.1,k,num2str(k),'FontSize',8,'Tag','bgaxGrid')
            end
            uistack(bgax,'bottom')
            app.BackgroundgridCheckBox.Value = 1;
        end
        
        function update_background_grid(app)
            bgax = findall(gcf,'type','axes','-and','tag','bgax');
            if isempty(bgax)
                [~]  = add_background_grid(app,[]);
            else
                [~]  = add_background_grid(app,bgax);
            end
        end
        
        function updateGUI_fig_position(app)
            set(gcf,'Units','centimeters')
            app.CentimetersButton.Value = 1;
            
            figpos = get(gcf,'position');
            app.x_fig.Value = round(figpos(1),1,'decimals');
            app.y_fig.Value = round(figpos(2),1,'decimals');
            app.w_fig.Value = round(figpos(3),1,'decimals');
            app.h_fig.Value = round(figpos(4),1,'decimals');
        end
        
        function add_axes_and_update_listbox(app,axpos)
            axes('next','add','units','centimeters','Position',axpos);
            add_axes_title_labels(app)
            fill_axes_listbox(app)
        end
        
        function axn = find_axes_in_figure(~)
            axn = findall(gcf,'type','axes','-not','tag','bgax');
            axn = flipud(axn); % flip so in order added
        end
        
        function add_axes_title_labels(app)
            axn = find_axes_in_figure(app);
            for n = 1:numel(axn)
                title(axn(n),['axes' num2str(n)], 'Units', 'normalized', 'Position', [0.5, 0.5]);
            end
        end
        
        function fill_axes_listbox(app)
            axn = find_axes_in_figure(app);
            nax   = numel(axn);
            
            naxes = transpose(1:nax);
            naxes = mat2cell(naxes,ones(length(naxes),1));
            naxes = cellfun(@num2str,naxes,'UniformOutput',0);
            
            axlist = cell(length(naxes),1);
            axlist(:) = {'axes'};
            axlist = strcat(axlist, naxes);
            
            app.AxesListBox.Items = axlist;
        end
        
        function selected_ax = get_axes_selected_in_listbox(app)
            allAxes       = app.AxesListBox.Items;
            selectedAxes  = app.AxesListBox.Value;
            selectedAxIdx = ismember(allAxes,selectedAxes);
            
            axn = find_axes_in_figure(app);
            
            if isempty(axn)
                selected_ax = [];
            else
                selected_ax = axn(selectedAxIdx);
                set(selected_ax,'selected','on')
                
                not_selected_ax = axn(~selectedAxIdx);
                set(not_selected_ax,'selected','off')
            end
        end
        
        function move_axes_in_listbox(app,direction)
            allAxes       = app.AxesListBox.Items;
            selectedAxes  = app.AxesListBox.Value;
            selectedAxIdx = find(ismember(allAxes,selectedAxes));
            
            if numel(selectedAxIdx) > 1
                msgbox('Can only move one axes listbox position at a time','Warning');
                return
            end
            
            switch direction
                case 'up'
                    newPosition   = selectedAxIdx - 1;
                    if newPosition == 0
                        newPosition = numel(allAxes);
                    end
                case 'down'
                    newPosition   = selectedAxIdx + 1;
                    if newPosition == numel(allAxes)+1
                        newPosition = 1;
                    end
            end
            
            axn = find_axes_in_figure(app);
            
            newAxOrder = 1:numel(axn);
            newAxOrder(newPosition)   = selectedAxIdx; % swap indices
            newAxOrder(selectedAxIdx) = newPosition;
            
            all_axpos = get(axn,'position'); % change axes positions
            new_axpos = all_axpos(newAxOrder);
            
            for n = 1:numel(axn)
                set(axn(n),'position',new_axpos{n})
            end
            
            app.AxesListBox.Value = allAxes(newPosition); % keep selection in listbox on axes that moved
        end


        function move_axes(~, ax, direction, value)
            axpos = get(ax,'position');
            
            switch direction
                case 'left'
                    x = axpos(1) - value;
                    y = axpos(2);
                case 'right'
                    x = axpos(1) + value;
                    y = axpos(2);
                case 'up'
                    x = axpos(1);
                    y = axpos(2) + value;
                case 'down'
                    x = axpos(1);
                    y = axpos(2) - value;
            end
            
            new_axpos = [x y axpos(3) axpos(4)];
            
            set(ax,'position',new_axpos)
            
        end
        
        function reset_tab(app,tab)
            switch tab
                case 'fig'
                    app.FigSizeDropDown.Value = 'Size';
                    app.x_fig.Value = 0;
                    app.y_fig.Value = 0;
                    app.w_fig.Value = 0;
                    app.h_fig.Value = 0;
                    app.CentimetersButton.Value = 1;
                    app.InsertFigname.Value = '';
                    app.BackgroundgridCheckBox.Value = 0;
            end
            
        end
        
        
        function updateGUI_ax_position(app,selected_ax)
            if isempty(selected_ax) % if no ax selected, update position GUI to default
                app.x_axes.Value = 0;
                app.y_axes.Value = 0;
                app.w_axes.Value = 0;
                app.h_axes.Value = 0;
                return
            end
            
            axpos = get(selected_ax,'position');
            
            if iscell(axpos)  % when multiple axes selected just display position of the first one
                axpos = axpos{1};
            end
            
            app.x_axes.Value = axpos(1);
            app.y_axes.Value = axpos(2);
            app.w_axes.Value = axpos(3);
            app.h_axes.Value = axpos(4);
        end


        function axpos = find_axes_grid_positions(app,gridpos)
            
            find_ax_size = @(figDimension,gap,n_plots)(figDimension - (gap*(n_plots+1)))/n_plots;
            
            nrow = app.nrowEditField.Value;
            ncol = app.ncolEditField.Value;
            gapx = app.GapxEditField.Value;
            gapy = app.GapyEditField.Value;
            
            w = find_ax_size(gridpos(3),gapx,ncol);
            h = find_ax_size(gridpos(4),gapy,nrow);
            
            x_pos = NaN(ncol,1);
            y_pos = NaN(nrow,1);
            axpos = NaN(ncol*nrow,4);
            yind  = NaN(nrow,ncol);
            xind  = NaN(nrow,ncol);
            
            for nc = 1:ncol
                x_pos(nc,1) = gridpos(1) + gapx  + ((w+gapx)*(nc-1));
                xind(:,nc) = nc;
            end
            for nr = 1:nrow
                y_pos(nr,1) = gridpos(2) + gapy + ((h+gapy)*(nr-1));
                yind(nr,:) = nr;
            end
            
            y_pos = flipud(y_pos);
            
            xind = transpose(xind);
            xind = xind(:);
            yind = transpose(yind);
            yind = yind(:);
            
            for n = 1 : nrow*ncol
                axpos(n,:) = [x_pos(xind(n)) y_pos(yind(n)) w h];
            end
        end
        
        function txtLbls = find_textlabels_in_figure(~)
            txtLbls = findall(gcf,'type','textbox','-and','tag','Lbl');
            txtLbls = flipud(txtLbls);
        end
        
        function fill_textlabel_listbox(app)
            txtLbls = find_textlabels_in_figure(app);
            Lblstr  = get(txtLbls,'String');
            
            if isempty(Lblstr)
                Lblstr = "";
            elseif  iscell(Lblstr)
                if numel(Lblstr) >1
                    Lblstr  = [Lblstr{:}];
                end
            else
                Lblstr = {Lblstr};
            end
            app.LabelListBox.Items = Lblstr;
        end
        
        function selected_txt = get_textlabel_selected_in_listbox(app)
            allLabels      = app.LabelListBox.Items;
            selectedLbls   = app.LabelListBox.Value;
            selectedLblIdx = ismember(allLabels,selectedLbls);
            
            txtLbls = find_textlabels_in_figure(app);
            
            if isempty(txtLbls)
                selected_txt = [];
            else
                selected_txt = txtLbls(selectedLblIdx);
                set(selected_txt,'selected','on')
                
                not_selected_txt = txtLbls(~selectedLblIdx);
                set(not_selected_txt,'selected','off')
            end
        end
        
        function updateGUI_textlabel_info(app,selected_txt)
            if isempty(selected_txt) % if no ax selected, update position GUI to default
                app.LabelEditField.Value = "";
                app.x_label.Value = 0;
                app.y_label.Value = 0;
                app.ItalicsCheckBox.Value = 0;
                app.BoldCheckBox.Value = 1;
                return
            end
            
            if numel(selected_txt) > 1 % when multiple axes selected just display position of the first one
                selected_txt = selected_txt(1);
            end
            
            lblpos = get(selected_txt,'position');
            lblstr = get(selected_txt,'String');
            
            lblsze = get(selected_txt,'FontSize');
            lblwgt = get(selected_txt,'FontWeight');
            lblang = get(selected_txt,'FontAngle');
            
            switch lblwgt
                case 'bold'
                    app.BoldCheckBox.Value = 1;
                case 'normal'
                    app.BoldCheckBox.Value = 0;
            end
            switch lblang
                case 'italic'
                    app.ItalicsCheckBox.Value = 1;
                case 'normal'
                    app.ItalicsCheckBox.Value = 0;
            end
            
            app.x_label.Value = lblpos(1);
            app.y_label.Value = lblpos(2);
            
            app.FontDropDown.Value = num2str(lblsze);
            
            app.LabelEditField.Value = string(lblstr);
        end


        function figfp = get_figure_export_path(app,ext)
            
            if ~contains(ext,'.')
                ext = ['.' ext];
            end
            
            figname     = app.InsertFigname.Value;
            figsavename = [figname ext];
            [fn,fp,idx] = uiputfile(figsavename,'Export figure');
            if idx == 0
                figfp =[];
                return
            end
            figfp =fullfile(fp,fn);
        end
        
   
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            size_options = {'Size','optimal_laptop','optimal_monitor','paper_figure','fullscreen','halfscreen','A4_vertical','A4_horizontal'};
            app.FigSizeDropDown.Items = size_options;
            set(0,'defaultfigurecolor',[1 1 1])  % sets figure background to white
        end

        % Button pushed function: CreateFigButton
        function CreateFigButtonPushed(app, event)
            create_figure(app)
        end

        % Button pushed function: CreateCustomFigButton
        function CreateCustomFigButtonPushed(app, event)
            create_custom_figure(app)
        end

        % Button pushed function: EditFigureButton
        function EditFigureButtonPushed(app, event)
            update_figure(app)
            update_background_grid(app)
            updateGUI_fig_position(app)
        end

        % Button pushed function: UpdateFigureButton
        function UpdateFigureButtonPushed(app, event)
            updateGUI_fig_position(app)
            update_figure(app) % values are rounded, adjust figure to match GUI exactly
            update_background_grid(app)
        end

        % Value changed function: BackgroundgridCheckBox
        function BackgroundgridCheckBoxValueChanged(app, event)
            value = app.BackgroundgridCheckBox.Value;
            if value == 0
                bgax = findall(gcf,'type','axes','position',[0 0 1 1]);
                delete(bgax)
            else
                update_background_grid(app)
            end
        end

        % Value changed function: InsertFigname
        function InsertFignameValueChanged(app, event)
            figname = app.InsertFigname.Value;
            fig = gcf;
            fig.Name = figname;
        end

        % Button pushed function: loadgfcButton
        function loadgfcButtonPushed(app, event)
            updateGUI_fig_position(app)
            update_figure(app) % values are rounded, adjust figure to match GUI exactly
            update_background_grid(app)
            fill_axes_listbox(app)
            fig = gcf;
            if ~isempty(fig.Name)
                app.InsertFigname.Value = fig.Name;
            end
        end

        % Button pushed function: loadmButton
        function loadmButtonPushed(app, event)
           [fn,fp,idx] = uigetfile('*.m','Select the figure code file to load');
           if idx == 0
               return
           end
            run(fullfile(fp,fn));
            updateGUI_fig_position(app)
            update_figure(app) % values are rounded, adjust figure to match GUI exactly
            update_background_grid(app)
            fill_axes_listbox(app)
            fig = gcf;
            if ~isempty(fig.Name)
                app.InsertFigname.Value = fig.Name;
            end
        end

        % Button pushed function: loadfigButton
        function loadfigButtonPushed(app, event)
            [fn,fp,idx] = uigetfile('*.fig','Select the figure code file to load');
            if idx == 0
               return
            end
            openfig(fullfile(fp,fn));
            updateGUI_fig_position(app)
            update_figure(app) % values are rounded, adjust figure to match GUI exactly
            update_background_grid(app)
            fill_axes_listbox(app)
            fig = gcf;
            if ~isempty(fig.Name)
                app.InsertFigname.Value = fig.Name;
            end
        end

        % Button pushed function: savemButton
        function savemButtonPushed(app, event)
            
            bgax = findall(gcf,'tag','bgax');
            delete(bgax)
        
            figname     = app.InsertFigname.Value;
            figsavename = [figname '_makeFigure.m'];
            
            [fn,fp,idx] = uiputfile(figsavename);
            if idx == 0
                return
            end
            
            mfile = fullfile(fp, fn);
            
            fileID = fopen(mfile,'w');
            
            figpos  = get(gcf,'Position');
            figaxes = find_axes_in_figure(app);
            
            fprintf(fileID,'\n');
            fprintf(fileID,'%s\r\n',['function axn = ' erase(fn,'.m')]);
            fprintf(fileID,'%s\r\n',['% function to make figure: ' figname]);
            fprintf(fileID,'\n');
            fprintf(fileID, '%s\r\n',['fig = figure(''units'',''centimeters'',''position'',[' num2str(figpos) ']);'] );
            fprintf(fileID,'%s\r\n',['fig.Name = ''' figname ''' ;']);
            fprintf(fileID,'\n');
                                   
            for n = 1:size(figaxes,1)
                axpos  = figaxes(n).Position;
                axview = figaxes(n).View;
                fprintf(fileID,'%s\r\n',['axn(' num2str(n) ') = axes(''next'',''add'',''units'',''centimeters'',''Position'',[' num2str(axpos) '],''View'', [' num2str(axview) ']);'] );
                fprintf(fileID,'%s\r\n',['title(''' 'axes' num2str(n) ''',''Units'', ''normalized'', ''Position'', [0.5, 0.5]);']);
                fprintf(fileID,'\n');
            end
            
            txtboxes = findall(gcf,'type','textbox');
            
            for nt = 1:size(txtboxes,1)
                txtpos = txtboxes(nt).Position;
                txtstr = txtboxes(nt).String;
                if iscell(txtstr)
                    txtstr = txtstr{1};
                end
                txtsze = txtboxes(nt).FontSize;
                txtwgt = txtboxes(nt).FontWeight;
                txtang = txtboxes(nt).FontAngle;
                
                fprintf(fileID,'%s\r\n',['annotation(gcf,''textbox'',''units'',''centimeters'',''position'', [' num2str(txtpos) '] ,''String'',''' txtstr ''',''FontSize'',' num2str(txtsze) ' ,''FontWeight'',''' txtwgt ''',''FontAngle'',''' txtang ''',''LineStyle'',''none'',''VerticalAlignment'',''middle'',''HorizontalAlignment'',''center'',''Margin'',0,''Tag'',''Lbl'');']);
                fprintf(fileID,'\n');
            end
            
            fprintf(fileID,'\n');
            fclose(fileID);
            
        end

        % Button pushed function: savefigButton
        function savefigButtonPushed(app, event)
            bgax = findall(gcf,'type','axes','position',[0 0 1 1]);
            delete(bgax)
                
            figname     = app.InsertFigname.Value;
            figsavename = [figname '.fig'];
            [fn,fp] = uiputfile(figsavename);
            figfp   = fullfile(fp, fn);
            savefig(figfp)
        end

        % Button pushed function: jpgButton
        function jpgButtonPushed(app, event)
            bgax = findall(gcf,'type','axes','position',[0 0 1 1]);
            delete(bgax)
            figfp = get_figure_export_path(app,'.jpg');
            if isempty(figfp)
                return
            end
            res = app.ResolutionEditField.Value;
            res = ['-r' num2str(res)];
            print(gcf,figfp,'-djpeg',res);
            
        end

        % Button pushed function: tiffButton
        function tiffButtonPushed(app, event)
            bgax = findall(gcf,'type','axes','position',[0 0 1 1]);
            delete(bgax)
            figfp = get_figure_export_path(app,'.tiff');
            if isempty(figfp)
                return
            end
            res   = app.ResolutionEditField.Value;
            res   = ['-r' num2str(res)];
            print(gcf,figfp,'-dtiff',res);
        end

        % Button pushed function: pdfButton
        function pdfButtonPushed(app, event)
            warning('off','MATLAB:print:FigureTooLargeForPage')
            bgax = findall(gcf,'type','axes','position',[0 0 1 1]);
            delete(bgax)
            figfp = get_figure_export_path(app,'.pdf');
            if isempty(figfp)
                return
            end
            res   = app.ResolutionEditField.Value;
            res   = ['-r' num2str(res)];
            figpos = get(gcf,'Position');
            set(gcf,'PaperUnits','centimeters','PaperSize',[figpos(3) figpos(4)]);
            print(gcf,figfp,'-dpdf',res);
        end

        % Button pushed function: AddAxesButton
        function AddAxesButtonPushed(app, event)
            
            x = app.x_axes.Value;
            y = app.y_axes.Value;
            w = app.w_axes.Value;
            h = app.h_axes.Value;
            
            axpos = [x y w h];
            
            add_axes_and_update_listbox(app,axpos)
            
        end

        % Button pushed function: epsButton
        function epsButtonPushed(app, event)
            figfp = get_figure_export_path(app,'.eps');
            if isempty(figfp)
                return
            end
            res   = app.ResolutionEditField.Value;
            res   = ['-r' num2str(res)];
            print(gcf,figfp,'-depsc',res);
        end

        % Button pushed function: svgButton
        function svgButtonPushed(app, event)
           figfp = get_figure_export_path(app,'.svg');  
           if isempty(figfp)
                return
            end
           res   = app.ResolutionEditField.Value;
           res   = ['-r' num2str(res)];
           print(gcf,figfp,'-dsvg',res); 
        end

        % Button pushed function: CloseFigureButton
        function CloseFigureButtonPushed(app, event)
            close(gcf)
            reset_tab(app,'fig')
        end

        % Button pushed function: EditAxesButton
        function EditAxesButtonPushed(app, event)
            
            selected_ax = get_axes_selected_in_listbox(app);
            
            if numel(selected_ax) > 1
                answer = questdlg('Do you really want to edit more than one axes? They will all end up in the same place...', ...
                   	'Edit warning', ...
                   	'Yes','No','No');
                if contains(answer,'No')
                    return
                end
               
            end
            
            x = app.x_axes.Value;
            y = app.y_axes.Value;
            w = app.w_axes.Value;
            h = app.h_axes.Value;
            
            set(selected_ax,'position',[x y w h])
           
        end

        % Button pushed function: DeleteAxesButton
        function DeleteAxesButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            delete(selected_ax)
            add_axes_title_labels(app)
            fill_axes_listbox(app)
            selected_ax = get_axes_selected_in_listbox(app);
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: UpdateAxesButton
        function UpdateAxesButtonPushed(app, event)
            add_axes_title_labels(app)
            fill_axes_listbox(app)
            selected_ax = get_axes_selected_in_listbox(app);
            updateGUI_ax_position(app,selected_ax)
        end

        % Value changed function: AxesListBox
        function AxesListBoxValueChanged(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: moveAxUpListButton
        function moveAxUpListButtonPushed(app, event)
            move_axes_in_listbox(app,'up')
            [~] = get_axes_selected_in_listbox(app); % so highlights current selections
        end

        % Button pushed function: moveAxDownListButton
        function moveAxDownListButtonPushed(app, event)
            move_axes_in_listbox(app,'down')
            [~] = get_axes_selected_in_listbox(app); % so highlights current selections
        end

        % Button pushed function: WorkspaceButton
        function WorkspaceButtonPushed(app, event)
            evalin('base','add_axes_to_workspace')
        end

        % Button pushed function: AddAxesGridButton
        function AddAxesGridButtonPushed(app, event)
            if app.FullAreaButton.Value
                gridpos = get(gcf,'position');
                gridpos(1:2) = 0;
            elseif app.CustomAreaButton.Value
                answer = inputdlg('Enter x y w h of area for axes grid (space separated):',...
                    'Custom axes grid area', [1 50]);
                gridpos = str2num(answer{1});
            end
            
            axpos = find_axes_grid_positions(app,gridpos);
            
            for n = 1:size(axpos,1)
                add_axes_and_update_listbox(app,axpos(n,:))
            end
            
        end

        % Button pushed function: DuplicateLeftButton
        function DuplicateLeftButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            gap = app.GapEditField.Value;
            
            if numel(selected_ax)~= 1
                msgbox('Select one axes to duplicate','Warning');
                return
            end
            
            axpos     = get(selected_ax,'position');
            new_x     = axpos(1) - (axpos(3)+gap);
            new_axpos = [new_x axpos(2:4)] ;
            
            add_axes_and_update_listbox(app,new_axpos)
            
            nax = numel(findall(gcf,'type','axes'))-1;  % select new axis in listbox
            app.AxesListBox.Value = ['axes' num2str(nax)];
            selected_ax = get_axes_selected_in_listbox(app); % so also highlights new selections
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: DuplicateRightButton
        function DuplicateRightButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            gap = app.GapEditField.Value;
            
            if numel(selected_ax)~= 1
                msgbox('Select one axes to duplicate','Warning');
                return
            end
            
            axpos     = get(selected_ax,'position');
            new_x     = axpos(1) + (axpos(3)+gap);
            new_axpos = [new_x axpos(2:4)] ;
            
            add_axes_and_update_listbox(app,new_axpos)
            
            nax = numel(findall(gcf,'type','axes'))-1;  % select new axis in listbox
            app.AxesListBox.Value = ['axes' num2str(nax)];
            selected_ax = get_axes_selected_in_listbox(app); % so also highlights new selections
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: DuplicateUpButton
        function DuplicateUpButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            gap = app.GapEditField.Value;
            
            if numel(selected_ax)~= 1
                msgbox('Select one axes to duplicate','Warning');
                return
            end
            
            axpos     = get(selected_ax,'position');
            new_y     = axpos(2) + (axpos(4)+gap);
            new_axpos = [axpos(1) new_y axpos(3:4)] ;
            
            add_axes_and_update_listbox(app,new_axpos)
            
            nax = numel(findall(gcf,'type','axes'))-1;  % select new axis in listbox
            app.AxesListBox.Value = ['axes' num2str(nax)];
            selected_ax = get_axes_selected_in_listbox(app); % so also highlights new selections
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: DuplicateDownButton
        function DuplicateDownButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            gap = app.GapEditField.Value;
            
            if numel(selected_ax)~= 1
                msgbox('Select one axes to duplicate','Warning');
                return
            end
            
            axpos     = get(selected_ax,'position');
            new_y     = axpos(2) - (axpos(4)+gap);
            new_axpos = [axpos(1) new_y axpos(3:4)] ;
            
            add_axes_and_update_listbox(app,new_axpos)
            
            nax = numel(findall(gcf,'type','axes'))-1;  % select new axis in listbox
            app.AxesListBox.Value = ['axes' num2str(nax)];
            selected_ax = get_axes_selected_in_listbox(app); % so also highlights new selections
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: MoveLeftButton
        function MoveLeftButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            move_dist   = app.DistEditField.Value;
            
            for n = 1:numel(selected_ax)
                move_axes(app,selected_ax(n),'left',move_dist)
            end
            
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: MoveRightButton
        function MoveRightButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            move_dist   = app.DistEditField.Value;
            
            for n = 1:numel(selected_ax)
                move_axes(app,selected_ax(n),'right',move_dist)
            end
            
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            move_dist   = app.DistEditField.Value;
            
            for n = 1:numel(selected_ax)
                move_axes(app,selected_ax(n),'up',move_dist)
            end
            
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            move_dist   = app.DistEditField.Value;
            
            for n = 1:numel(selected_ax)
                move_axes(app,selected_ax(n),'down',move_dist)
            end
            
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: AlignLeftLButton
        function AlignLeftLButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            
            if numel(selected_ax) == 1  % single axes selected, align L axis to L grid
                axpos     = get(selected_ax,'position');
                xTarget   = floor(axpos(1));
                move_dist = axpos(1) - xTarget;
                move_axes(app,selected_ax,'left',move_dist)
            else % if multiple axes selected, align to the leftmost L axis
                axpos   = cell2mat(get(selected_ax,'position'));
                xTarget = min(axpos(:,1));
                for n = 1:numel(selected_ax)
                    move_dist = axpos(n,1) - xTarget;
                    move_axes(app,selected_ax(n),'left',move_dist)
                end
            end
            
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: AlignRightLButton
        function AlignRightLButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            
            if numel(selected_ax) == 1  % single axes selected, align R axis to L grid
                axpos     = get(selected_ax,'position');
                xR        = axpos(1) + axpos(3);
                xTarget   = floor(xR);
                move_dist = xR - xTarget;
                move_axes(app,selected_ax,'left',move_dist)
            else % if multiple axes selected, align R axis to the leftmost R axis
                axpos   = cell2mat(get(selected_ax,'position'));
                xR      = axpos(:,1) + axpos(:,3);
                xTarget = min(xR);
                for n = 1:numel(selected_ax)
                    move_dist = xR(n,1) - xTarget;
                    move_axes(app,selected_ax(n),'left',move_dist)
                end
            end
            
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: AlignTopUButton
        function AlignTopUButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            
            if numel(selected_ax) == 1  % single axes selected, align T axis to T grid
                axpos     = get(selected_ax,'position');
                yT        = axpos(2) + axpos(4);
                yTarget   = ceil(yT);
                move_dist = yTarget - yT;
                move_axes(app,selected_ax,'up',move_dist)
            else % if multiple axes selected, align T axis to the topmost T axis
                axpos   = cell2mat(get(selected_ax,'position'));
                yT      = axpos(:,2) + axpos(:,4);
                yTarget = max(yT);
                for n = 1:numel(selected_ax)
                    move_dist = yTarget - yT(n,1);
                    move_axes(app,selected_ax(n),'up',move_dist)
                end
            end
            
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: AlignBottomUButton
        function AlignBottomUButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            
            if numel(selected_ax) == 1  % single axes selected, align B axis to T grid
                axpos     = get(selected_ax,'position');
                yTarget   = ceil(axpos(2));
                move_dist = yTarget - axpos(2);
                move_axes(app,selected_ax,'up',move_dist)
            else % if multiple axes selected, align B axis to the topmost B axis
                axpos   = cell2mat(get(selected_ax,'position'));
                yTarget = max(axpos(:,2));
                for n = 1:numel(selected_ax)
                    move_dist = yTarget - axpos(n,2);
                    move_axes(app,selected_ax(n),'up',move_dist)
                end
            end
            
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: AlignLeftRButton
        function AlignLeftRButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            
            if numel(selected_ax) == 1  % single axes selected, align L axis to R grid
                axpos     = get(selected_ax,'position');
                xTarget   = ceil(axpos(1));
                move_dist = xTarget - axpos(1);
                move_axes(app,selected_ax,'right',move_dist)
            else % if multiple axes selected, align to the rightmost L axis
                axpos = cell2mat(get(selected_ax,'position'));
                xTarget = max(axpos(:,1));
                for n = 1:numel(selected_ax)
                    move_dist = xTarget - axpos(n,1);
                    move_axes(app,selected_ax(n),'right',move_dist)
                end
            end
            
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: AlignRightRButton
        function AlignRightRButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            
            if numel(selected_ax) == 1  % single axes selected, align R axis to R grid
                axpos     = get(selected_ax,'position');
                xR        = axpos(1) + axpos(3);
                xTarget   = ceil(xR);
                move_dist = xTarget - xR;
                move_axes(app,selected_ax,'right',move_dist)
            else % if multiple axes selected, align R axis to the rightmost R axis
                axpos   = cell2mat(get(selected_ax,'position'));
                xR      = axpos(:,1) + axpos(:,3);
                xTarget = max(xR);
                for n = 1:numel(selected_ax)
                    move_dist = xTarget - xR(n,1);
                    move_axes(app,selected_ax(n),'right',move_dist)
                end
            end
            
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: AlignTopBButton
        function AlignTopBButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            
            if numel(selected_ax) == 1  % single axes selected, align T axis to B grid
                axpos     = get(selected_ax,'position');
                yT        = axpos(2) + axpos(4);
                yTarget   = floor(yT);
                move_dist = yT - yTarget;
                move_axes(app,selected_ax,'down',move_dist)
            else % if multiple axes selected, align T axis to the bottommost T axis
                axpos   = cell2mat(get(selected_ax,'position'));
                yT      = axpos(:,2) + axpos(:,4);
                yTarget = min(yT);
                for n = 1:numel(selected_ax)
                    move_dist = yT(n,1) - yTarget;
                    move_axes(app,selected_ax(n),'down',move_dist)
                end
            end
            
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: AlignBottomBButton
        function AlignBottomBButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            
            if numel(selected_ax) == 1  % single axes selected, align B axis to B grid
                axpos     = get(selected_ax,'position');
                yTarget   = floor(axpos(2));
                move_dist = axpos(2) - yTarget;
                move_axes(app,selected_ax,'down',move_dist)
            else % if multiple axes selected, align B axis to the bottommost B axis
                axpos   = cell2mat(get(selected_ax,'position'));
                yTarget = min(axpos(:,2));
                for n = 1:numel(selected_ax)
                    move_dist = axpos(n,2) - yTarget;
                    move_axes(app,selected_ax(n),'down',move_dist)
                end
            end
            
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: WupButton
        function WupButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            
            if isempty(selected_ax)
                msgbox('Select axes to scale','Warning');
                return
            elseif numel(selected_ax) == 1  % single axes selected, scale width up to grid
                axpos = get(selected_ax,'position');
                x1    = axpos(1) - floor(axpos(1));
                x2    = ceil(axpos(1)+axpos(3)) - (axpos(1)+axpos(3));
                xext  = x1 + x2;
              
                set(selected_ax,'position',[floor(axpos(1)) axpos(2) axpos(3)+xext axpos(4)])
                
            else % if multiple axes selected, scale smallest width to largest
                axpos  = cell2mat(get(selected_ax,'position'));
                new_w  = max(axpos(:,3));
                for n = 1:numel(selected_ax)
                    set(selected_ax(n),'position',[axpos(n,1) axpos(n,2) new_w axpos(n,4)])
                end
            end
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: HupButton
        function HupButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            
            if isempty(selected_ax)
                msgbox('Select axes to scale','Warning');
                return
            elseif numel(selected_ax) == 1  % single axes selected, scale height up to grid
                axpos = get(selected_ax,'position');
                y1    = axpos(2) - floor(axpos(2));
                y2    = ceil(axpos(2)+axpos(4)) - (axpos(2)+axpos(4));
                yext  = y1 + y2;
                set(selected_ax,'position',[axpos(1) floor(axpos(2)) axpos(3) axpos(4)+yext])
                
            else % if multiple axes selected, scale smallest height to largest
                axpos  = cell2mat(get(selected_ax,'position'));
                new_h  = max(axpos(:,4));
                for n = 1:numel(selected_ax)
                    set(selected_ax(n),'position',[axpos(n,1) axpos(n,2) axpos(n,3) new_h])
                end
            end
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: WidthHeightupButton
        function WidthHeightupButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            
            if isempty(selected_ax)
                msgbox('Select axes to scale','Warning');
                return
            elseif numel(selected_ax) == 1  % single axes selected, scale width and height up to grid
                axpos = get(selected_ax,'position');
                x1    = axpos(1) - floor(axpos(1));
                x2    = ceil(axpos(1)+axpos(3)) - (axpos(1)+axpos(3));
                xext  = x1 + x2;
                y1    = axpos(2) - floor(axpos(2));
                y2    = ceil(axpos(2)+axpos(4)) - (axpos(2)+axpos(4));
                yext  = y1 + y2;
                set(selected_ax,'position',[floor(axpos(1)) floor(axpos(2)) axpos(3)+xext axpos(4)+yext])
                
            else % if multiple axes selected, scale smallest widthand height to largest
                axpos  = cell2mat(get(selected_ax,'position'));
                new_w  = max(axpos(:,3));
                new_h  = max(axpos(:,4));
                for n = 1:numel(selected_ax)
                    set(selected_ax(n),'position',[axpos(n,1) axpos(n,2) new_w new_h])
                end
            end
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: WdownButton
        function WdownButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            
            if isempty(selected_ax)
                msgbox('Select axes to scale','Warning');
                return
            elseif numel(selected_ax) == 1  % single axes selected, scale width up to grid
                axpos = get(selected_ax,'position');
                x1    = ceil(axpos(1)) - axpos(1);
                x2    = (axpos(1)+axpos(3)) - floor(axpos(1)+axpos(3));
                xred  = x1 + x2;
                
                set(selected_ax,'position',[ceil(axpos(1)) axpos(2) axpos(3)-xred axpos(4)])
                
            else % if multiple axes selected, scale smallest width to largest
                axpos  = cell2mat(get(selected_ax,'position'));
                new_w  = min(axpos(:,3));
                for n = 1:numel(selected_ax)
                    set(selected_ax(n),'position',[axpos(n,1) axpos(n,2) new_w axpos(n,4)])
                end
            end
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: HdownButton
        function HdownButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            
            if isempty(selected_ax)
                msgbox('Select axes to scale','Warning');
                return
            elseif numel(selected_ax) == 1  % single axes selected, scale height down to grid
                axpos = get(selected_ax,'position');
                y1    = ceil(axpos(2)) - axpos(2);
                y2    = (axpos(2)+axpos(4)) - floor(axpos(2)+axpos(4));
                yred  = y1 + y2;
                set(selected_ax,'position',[axpos(1) ceil(axpos(2)) axpos(3) axpos(4)-yred])
                
            else % if multiple axes selected, scale smallest height to smallest
                axpos  = cell2mat(get(selected_ax,'position'));
                new_h  = min(axpos(:,4));
                for n = 1:numel(selected_ax)
                    set(selected_ax(n),'position',[axpos(n,1) axpos(n,2) axpos(n,3) new_h])
                end
            end
            updateGUI_ax_position(app,selected_ax)
        end

        % Button pushed function: WidthHeightdownButton
        function WidthHeightdownButtonPushed(app, event)
            selected_ax = get_axes_selected_in_listbox(app);
            
            if isempty(selected_ax)
                msgbox('Select axes to scale','Warning');
                return
            elseif numel(selected_ax) == 1  % single axes selected, scale width up to grid
                axpos = get(selected_ax,'position');
                x1    = ceil(axpos(1)) - axpos(1);
                x2    = (axpos(1)+axpos(3)) - floor(axpos(1)+axpos(3));
                xred  = x1 + x2;
                y1    = ceil(axpos(2)) - axpos(2);
                y2    = (axpos(2)+axpos(4)) - floor(axpos(2)+axpos(4));
                yred  = y1 + y2;
                set(selected_ax,'position',[ceil(axpos(1)) ceil(axpos(2)) axpos(3)-xred axpos(4)-yred])
                
            else % if multiple axes selected, scale smallest width to largest
                axpos  = cell2mat(get(selected_ax,'position'));
                new_w  = min(axpos(:,3));
                new_h  = min(axpos(:,4));
                for n = 1:numel(selected_ax)
                    set(selected_ax(n),'position',[axpos(n,1) axpos(n,2) new_w new_h])
                end
            end
            updateGUI_ax_position(app,selected_ax)
        end

        % Value changed function: RotateAxesEditField
        function RotateAxesEditFieldValueChanged(app, event)
            rotVal = app.RotateAxesEditField.Value;
            selected_ax = get_axes_selected_in_listbox(app);
            if ~isempty(selected_ax)
                set(selected_ax,'View',[rotVal,90]);
            end
        end

        % Button pushed function: AddLabelButton
        function AddLabelButtonPushed(app, event)
            lbltxt = app.LabelEditField.Value;
            lblx   = app.x_label.Value;
            lbly   = app.y_label.Value;
            lblsze = str2double(app.FontDropDown.Value);
            if app.BoldCheckBox.Value
                lblwgt = 'bold';
            else
                lblwgt = 'normal';
            end
            if app.ItalicsCheckBox.Value
                lblang = 'italic';
            else
                lblang = 'normal';
            end
            annotation(gcf,'textbox','units','centimeters','position',[lblx lbly 1 1],'String',{lbltxt},...
                'FontSize',lblsze,'FontWeight',lblwgt,'FontAngle',lblang,'LineStyle','none',...
                'VerticalAlignment','middle','HorizontalAlignment','center','Margin',0,'Tag','Lbl');
            fill_textlabel_listbox(app)
            app.LabelEditField.Value = '';
        end

        % Value changed function: LabelListBox
        function LabelListBoxValueChanged(app, event)
            selected_txt = get_textlabel_selected_in_listbox(app);
            updateGUI_textlabel_info(app,selected_txt);
            fill_textlabel_listbox(app)
        end

        % Button pushed function: DeleteLabelButton
        function DeleteLabelButtonPushed(app, event)
            selected_txt = get_textlabel_selected_in_listbox(app);
            delete(selected_txt)
            fill_textlabel_listbox(app)
            selected_txt = get_axes_selected_in_listbox(app);
            updateGUI_textlabel_info(app,selected_txt)
        end

        % Button pushed function: UpdateLabelButton
        function UpdateLabelButtonPushed(app, event)
            fill_textlabel_listbox(app)
            selected_txt = get_axes_selected_in_listbox(app);
            updateGUI_textlabel_info(app,selected_txt)
        end

        % Button pushed function: EditLabelButton
        function EditLabelButtonPushed(app, event)
            selected_txt = get_textlabel_selected_in_listbox(app);
            
            lbltxt = app.LabelEditField.Value;
            lblx   = app.x_label.Value;
            lbly   = app.y_label.Value;
            lblsze = str2double(app.FontDropDown.Value);
            if app.BoldCheckBox.Value
                lblwgt = 'bold';
            else
                lblwgt = 'normal';
            end
            if app.ItalicsCheckBox.Value
                lblang = 'italic';
            else
                lblang = 'normal';
            end
            
            set(selected_txt,'position',[lblx lbly 1 1],'String',{lbltxt},...
                'FontSize',lblsze,'FontWeight',lblwgt,'FontAngle',lblang,'LineStyle','none',...
                'VerticalAlignment','middle','HorizontalAlignment','center','Margin',0,'Tag','Lbl');
            fill_textlabel_listbox(app)
        end

        % Button pushed function: helpCreateFig
        function helpCreateFigButtonPushed(app, event)
            msgtxt = {'To create figure select predefinied figure size from drop down list and click "Create"';...
                '';...
                'To add new predefined figure size add dimensions to create_figure function (line 115) and add name to size_options (line 405) in startupFnc'};
            msgbox(msgtxt,'Create figure help');
        end

        % Button pushed function: helpCustomFig
        function helpCustomFigButtonPushed(app, event)
            msgtxt = {'To create custom figure select units and input dimensions (x and y value for bottom left corner, width and height of figure) and click "Create"';...
                '';...
                'Normalised units are normalized wih respect to the screen. The lower-left corner maps to (0,0) and the upper-right corner maps to (1,1)';...
                '';...
                'To edit figure size, change dimension value and click "Edit"';
                '';...
                'Click "Update" to load figure parameters into GUI and fix background grid in case of manual adjustment of figure size'};
            msgbox(msgtxt,'Create custom figure help');
        end

        % Button pushed function: helpFigProperties
        function helpFigPropertiesButtonPushed(app, event)
            msgtxt = {'Input figure name here.';...
                'Figure name will be suffixed with "_makeFigure" to make file name when saving .m file.';...
                '';...
                'Background axes can be added or removed with this checkbox.';...
                'NB background axes will not be saved'};
            msgbox(msgtxt,'Figure properties help');
        end

        % Callback function
        function FiletypeEditFieldValueChanged(app, event)
              fext  = app.FiletypeEditField.Value;
            figfp = get_figure_export_path(app,fext);     
            fext = ['-d' fext];
            fext =  erase(fext,'.');
                 res   = app.ResolutionEditField.Value;
           res   = ['-r' num2str(res)];
           print(gcf,figfp,fext,res); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.design_figureUIFigure = uifigure('Visible', 'off');
            app.design_figureUIFigure.Name = 'design_figure';
            app.TabGroup = uitabgroup(app.design_figureUIFigure);
            app.FigTab = uitab(app.TabGroup);
            app.FigTab.Title = 'Fig.';
            app.FigurepropertiesPanel = uipanel(app.FigTab);
            app.FigurepropertiesPanel.Title = 'Figure properties';
            app.InsertFigname = uieditfield(app.FigurepropertiesPanel, 'text');
            app.InsertFigname.ValueChangedFcn = createCallbackFcn(app, @InsertFignameValueChanged, true);
            app.BackgroundgridCheckBox = uicheckbox(app.FigurepropertiesPanel);
            app.BackgroundgridCheckBox.ValueChangedFcn = createCallbackFcn(app, @BackgroundgridCheckBoxValueChanged, true);
            app.BackgroundgridCheckBox.Text = 'Background grid';
            app.LoadfigurePanel = uipanel(app.FigTab);
            app.LoadfigurePanel.Title = 'Load figure';
            app.loadmButton = uibutton(app.LoadfigurePanel, 'push');
            app.loadmButton.ButtonPushedFcn = createCallbackFcn(app, @loadmButtonPushed, true);
            app.loadmButton.Text = '.m ';
            app.loadgfcButton = uibutton(app.LoadfigurePanel, 'push');
            app.loadgfcButton.ButtonPushedFcn = createCallbackFcn(app, @loadgfcButtonPushed, true);
            app.loadgfcButton.Text = 'gfc';
            app.loadfigButton = uibutton(app.LoadfigurePanel, 'push');
            app.loadfigButton.ButtonPushedFcn = createCallbackFcn(app, @loadfigButtonPushed, true);
            app.loadfigButton.Text = '.fig';
            app.SavefigurePanel = uipanel(app.FigTab);
            app.SavefigurePanel.Title = 'Save figure';
            app.savemButton = uibutton(app.SavefigurePanel, 'push');
            app.savemButton.ButtonPushedFcn = createCallbackFcn(app, @savemButtonPushed, true);
            app.savemButton.Text = '.m ';
            app.savefigButton = uibutton(app.SavefigurePanel, 'push');
            app.savefigButton.ButtonPushedFcn = createCallbackFcn(app, @savefigButtonPushed, true);
            app.savefigButton.Text = '.fig';
            app.ExportfigurePanel = uipanel(app.FigTab);
            app.ExportfigurePanel.Title = 'Export figure';
            app.ResolutionEditFieldLabel = uilabel(app.ExportfigurePanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.ExportfigurePanel, 'numeric');
            app.ResolutionEditField.Value = 600;
            app.jpgButton = uibutton(app.ExportfigurePanel, 'push');
            app.jpgButton.ButtonPushedFcn = createCallbackFcn(app, @jpgButtonPushed, true);
            app.jpgButton.Text = '.jpg';
            app.tiffButton = uibutton(app.ExportfigurePanel, 'push');
            app.tiffButton.ButtonPushedFcn = createCallbackFcn(app, @tiffButtonPushed, true);
            app.tiffButton.Text = '.tiff';
            app.pdfButton = uibutton(app.ExportfigurePanel, 'push');
            app.pdfButton.ButtonPushedFcn = createCallbackFcn(app, @pdfButtonPushed, true);
            app.pdfButton.Text = '.pdf';
            app.epsButton = uibutton(app.ExportfigurePanel, 'push');
            app.epsButton.ButtonPushedFcn = createCallbackFcn(app, @epsButtonPushed, true);
            app.epsButton.Text = '.eps';
            app.svgButton = uibutton(app.ExportfigurePanel, 'push');
            app.svgButton.ButtonPushedFcn = createCallbackFcn(app, @svgButtonPushed, true);
            app.svgButton.Text = '.svg';
            app.CloseFigureButton = uibutton(app.FigTab, 'push');
            app.CloseFigureButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFigureButtonPushed, true);
            app.CloseFigureButton.Text = 'Close figure';
            app.CreatefigurePanel = uipanel(app.FigTab);
            app.CreatefigurePanel.Title = 'Create figure';
            app.FigSizeDropDown = uidropdown(app.CreatefigurePanel);
            app.FigSizeDropDown.Items = {'Size'};
            app.FigSizeDropDown.Editable = 'on';
            app.FigSizeDropDown.Value = 'Size';
            app.CreateFigButton = uibutton(app.CreatefigurePanel, 'push');
            app.CreateFigButton.ButtonPushedFcn = createCallbackFcn(app, @CreateFigButtonPushed, true);
            app.CreateFigButton.Text = 'Create';
            app.CustomsizePanel = uipanel(app.FigTab);
            app.CustomsizePanel.Title = 'Custom size';
            app.UnitsButtonGroup = uibuttongroup(app.CustomsizePanel);
            app.UnitsButtonGroup.Title = 'Units';
            app.CentimetersButton = uiradiobutton(app.UnitsButtonGroup);
            app.CentimetersButton.Text = 'Centimeters';
            app.CentimetersButton.Value = true;
            app.NormalizedButton = uiradiobutton(app.UnitsButtonGroup);
            app.NormalizedButton.Text = 'Normalized';
            app.InchesButton = uiradiobutton(app.UnitsButtonGroup);
            app.InchesButton.Text = 'Inches';
            app.CreateCustomFigButton = uibutton(app.CustomsizePanel, 'push');
            app.CreateCustomFigButton.ButtonPushedFcn = createCallbackFcn(app, @CreateCustomFigButtonPushed, true);
            app.CreateCustomFigButton.Text = 'Create';
            app.xEditField_2Label = uilabel(app.CustomsizePanel);
            app.xEditField_2Label.Text = 'x';
            app.x_fig = uieditfield(app.CustomsizePanel, 'numeric');
            app.yEditField_2Label = uilabel(app.CustomsizePanel);
            app.yEditField_2Label.Text = 'y';
            app.y_fig = uieditfield(app.CustomsizePanel, 'numeric');
            app.wEditField_2Label = uilabel(app.CustomsizePanel);
            app.wEditField_2Label.Text = 'w';
            app.w_fig = uieditfield(app.CustomsizePanel, 'numeric');
            app.hEditField_2Label = uilabel(app.CustomsizePanel);
            app.hEditField_2Label.Text = 'h';
            app.h_fig = uieditfield(app.CustomsizePanel, 'numeric');
            app.EditFigureButton = uibutton(app.CustomsizePanel, 'push');
            app.EditFigureButton.ButtonPushedFcn = createCallbackFcn(app, @EditFigureButtonPushed, true);
            app.EditFigureButton.Text = 'Edit';
            app.UpdateFigureButton = uibutton(app.CustomsizePanel, 'push');
            app.UpdateFigureButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateFigureButtonPushed, true);
            app.UpdateFigureButton.Text = 'Update';
            app.helpCreateFig = uibutton(app.FigTab, 'push');
            app.helpCreateFig.ButtonPushedFcn = createCallbackFcn(app, @helpCreateFigButtonPushed, true);
            app.helpCreateFig.Text = '?';
            app.helpCustomFig = uibutton(app.FigTab, 'push');
            app.helpCustomFig.ButtonPushedFcn = createCallbackFcn(app, @helpCustomFigButtonPushed, true);
            app.helpCustomFig.Text = '?';
            app.helpFigProperties = uibutton(app.FigTab, 'push');
            app.helpFigProperties.ButtonPushedFcn = createCallbackFcn(app, @helpFigPropertiesButtonPushed, true);
            app.helpFigProperties.Text = '?';
            app.helpLoadFig = uibutton(app.FigTab, 'push');
            app.helpLoadFig.Text = '?';
            app.helpSaveFig = uibutton(app.FigTab, 'push');
            app.helpSaveFig.Text = '?';
            app.helpExportFig = uibutton(app.FigTab, 'push');
            app.helpExportFig.Text = '?';
            app.AxesTab = uitab(app.TabGroup);
            app.AxesTab.Title = 'Axes';
            app.AddaxesPanel = uipanel(app.AxesTab);
            app.AddaxesPanel.Title = 'Add axes';
            app.xEditFieldLabel = uilabel(app.AddaxesPanel);
            app.xEditFieldLabel.Text = 'x';
            app.x_axes = uieditfield(app.AddaxesPanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.AddaxesPanel);
            app.yEditFieldLabel.Text = 'y';
            app.y_axes = uieditfield(app.AddaxesPanel, 'numeric');
            app.wEditFieldLabel = uilabel(app.AddaxesPanel);
            app.wEditFieldLabel.Text = 'w';
            app.w_axes = uieditfield(app.AddaxesPanel, 'numeric');
            app.hEditFieldLabel = uilabel(app.AddaxesPanel);
            app.hEditFieldLabel.Text = 'h';
            app.h_axes = uieditfield(app.AddaxesPanel, 'numeric');
            app.AddAxesButton = uibutton(app.AddaxesPanel, 'push');
            app.AddAxesButton.ButtonPushedFcn = createCallbackFcn(app, @AddAxesButtonPushed, true);
            app.AddAxesButton.Text = 'Add';
            app.EditAxesButton = uibutton(app.AddaxesPanel, 'push');
            app.EditAxesButton.ButtonPushedFcn = createCallbackFcn(app, @EditAxesButtonPushed, true);
            app.EditAxesButton.Text = 'Edit';
            app.DeleteAxesButton = uibutton(app.AddaxesPanel, 'push');
            app.DeleteAxesButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAxesButtonPushed, true);
            app.DeleteAxesButton.Text = 'Delete';
            app.UpdateAxesButton = uibutton(app.AddaxesPanel, 'push');
            app.UpdateAxesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAxesButtonPushed, true);
            app.UpdateAxesButton.Text = 'Update';
            app.ListBoxLabel = uilabel(app.AddaxesPanel);
            app.ListBoxLabel.Text = '';
            app.AxesListBox = uilistbox(app.AddaxesPanel);
            app.AxesListBox.Items = {};
            app.AxesListBox.Multiselect = 'on';
            app.AxesListBox.ValueChangedFcn = createCallbackFcn(app, @AxesListBoxValueChanged, true);
            app.AxesListBox.Value = {};
            app.moveAxUpListButton = uibutton(app.AddaxesPanel, 'push');
            app.moveAxUpListButton.ButtonPushedFcn = createCallbackFcn(app, @moveAxUpListButtonPushed, true);
            app.moveAxUpListButton.Text = '^';
            app.moveAxDownListButton = uibutton(app.AddaxesPanel, 'push');
            app.moveAxDownListButton.ButtonPushedFcn = createCallbackFcn(app, @moveAxDownListButtonPushed, true);
            app.moveAxDownListButton.Text = 'v';
            app.WorkspaceButton = uibutton(app.AddaxesPanel, 'push');
            app.WorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @WorkspaceButtonPushed, true);
            app.WorkspaceButton.Text = 'workspace';
            app.AddaxesgridPanel = uipanel(app.AxesTab);
            app.AddaxesgridPanel.Title = 'Add axes grid';
            app.AreaLabel = uilabel(app.AddaxesgridPanel);
            app.AreaLabel.Text = 'Area:';
            app.GridAreaButtonGroup = uibuttongroup(app.AddaxesgridPanel);
            app.FullAreaButton = uiradiobutton(app.GridAreaButtonGroup);
            app.FullAreaButton.Text = 'Full';
            app.FullAreaButton.Value = true;
            app.CustomAreaButton = uiradiobutton(app.GridAreaButtonGroup);
            app.CustomAreaButton.Text = 'Custom';
            app.GapxEditFieldLabel = uilabel(app.AddaxesgridPanel);
            app.GapxEditFieldLabel.Text = 'Gap x';
            app.GapxEditField = uieditfield(app.AddaxesgridPanel, 'numeric');
            app.GapxEditField.Value = 1;
            app.GapyEditFieldLabel = uilabel(app.AddaxesgridPanel);
            app.GapyEditFieldLabel.Text = 'Gap y';
            app.GapyEditField = uieditfield(app.AddaxesgridPanel, 'numeric');
            app.GapyEditField.Value = 1;
            app.ncolEditFieldLabel = uilabel(app.AddaxesgridPanel);
            app.ncolEditFieldLabel.Text = 'n col';
            app.ncolEditField = uieditfield(app.AddaxesgridPanel, 'numeric');
            app.nrowEditFieldLabel = uilabel(app.AddaxesgridPanel);
            app.nrowEditFieldLabel.Text = 'n row';
            app.nrowEditField = uieditfield(app.AddaxesgridPanel, 'numeric');
            app.AddAxesGridButton = uibutton(app.AddaxesgridPanel, 'push');
            app.AddAxesGridButton.ButtonPushedFcn = createCallbackFcn(app, @AddAxesGridButtonPushed, true);
            app.AddAxesGridButton.Text = 'Add';
            app.DuplicateaxesPanel = uipanel(app.AxesTab);
            app.DuplicateaxesPanel.Title = 'Duplicate axes';
            app.GapEditFieldLabel = uilabel(app.DuplicateaxesPanel);
            app.GapEditFieldLabel.Text = 'Gap';
            app.GapEditField = uieditfield(app.DuplicateaxesPanel, 'numeric');
            app.GapEditField.Value = 1;
            app.DuplicateLeftButton = uibutton(app.DuplicateaxesPanel, 'push');
            app.DuplicateLeftButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateLeftButtonPushed, true);
            app.DuplicateLeftButton.Text = '<';
            app.DuplicateRightButton = uibutton(app.DuplicateaxesPanel, 'push');
            app.DuplicateRightButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateRightButtonPushed, true);
            app.DuplicateRightButton.Text = '>';
            app.DuplicateUpButton = uibutton(app.DuplicateaxesPanel, 'push');
            app.DuplicateUpButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateUpButtonPushed, true);
            app.DuplicateUpButton.Text = '^';
            app.DuplicateDownButton = uibutton(app.DuplicateaxesPanel, 'push');
            app.DuplicateDownButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateDownButtonPushed, true);
            app.DuplicateDownButton.Text = 'v';
            app.cmLabel = uilabel(app.DuplicateaxesPanel);
            app.cmLabel.Text = 'cm';
            app.MoveaxesPanel = uipanel(app.AxesTab);
            app.MoveaxesPanel.Title = 'Move axes';
            app.DistEditFieldLabel = uilabel(app.MoveaxesPanel);
            app.DistEditFieldLabel.Text = 'Dist.';
            app.DistEditField = uieditfield(app.MoveaxesPanel, 'numeric');
            app.DistEditField.Value = 1;
            app.MoveLeftButton = uibutton(app.MoveaxesPanel, 'push');
            app.MoveLeftButton.ButtonPushedFcn = createCallbackFcn(app, @MoveLeftButtonPushed, true);
            app.MoveLeftButton.Text = '<';
            app.MoveRightButton = uibutton(app.MoveaxesPanel, 'push');
            app.MoveRightButton.ButtonPushedFcn = createCallbackFcn(app, @MoveRightButtonPushed, true);
            app.MoveRightButton.Text = '>';
            app.MoveUpButton = uibutton(app.MoveaxesPanel, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = '^';
            app.MoveDownButton = uibutton(app.MoveaxesPanel, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'v';
            app.cmLabel_2 = uilabel(app.MoveaxesPanel);
            app.cmLabel_2.Text = 'cm';
            app.AlignselectedaxesPanel = uipanel(app.AxesTab);
            app.AlignselectedaxesPanel.Title = 'Align selected axes';
            app.AlignLeftLButton = uibutton(app.AlignselectedaxesPanel, 'push');
            app.AlignLeftLButton.ButtonPushedFcn = createCallbackFcn(app, @AlignLeftLButtonPushed, true);
            app.AlignLeftLButton.Text = 'L <';
            app.AlignRightLButton = uibutton(app.AlignselectedaxesPanel, 'push');
            app.AlignRightLButton.ButtonPushedFcn = createCallbackFcn(app, @AlignRightLButtonPushed, true);
            app.AlignRightLButton.Text = 'R <';
            app.AlignTopUButton = uibutton(app.AlignselectedaxesPanel, 'push');
            app.AlignTopUButton.ButtonPushedFcn = createCallbackFcn(app, @AlignTopUButtonPushed, true);
            app.AlignTopUButton.Text = 'T ^';
            app.AlignBottomUButton = uibutton(app.AlignselectedaxesPanel, 'push');
            app.AlignBottomUButton.ButtonPushedFcn = createCallbackFcn(app, @AlignBottomUButtonPushed, true);
            app.AlignBottomUButton.Text = 'B ^';
            app.AlignLeftRButton = uibutton(app.AlignselectedaxesPanel, 'push');
            app.AlignLeftRButton.ButtonPushedFcn = createCallbackFcn(app, @AlignLeftRButtonPushed, true);
            app.AlignLeftRButton.Text = 'L >';
            app.AlignRightRButton = uibutton(app.AlignselectedaxesPanel, 'push');
            app.AlignRightRButton.ButtonPushedFcn = createCallbackFcn(app, @AlignRightRButtonPushed, true);
            app.AlignRightRButton.Text = 'R >';
            app.AlignTopBButton = uibutton(app.AlignselectedaxesPanel, 'push');
            app.AlignTopBButton.ButtonPushedFcn = createCallbackFcn(app, @AlignTopBButtonPushed, true);
            app.AlignTopBButton.Text = 'T v';
            app.AlignBottomBButton = uibutton(app.AlignselectedaxesPanel, 'push');
            app.AlignBottomBButton.ButtonPushedFcn = createCallbackFcn(app, @AlignBottomBButtonPushed, true);
            app.AlignBottomBButton.Text = 'B v';
            app.ScaleselectedaxesPanel = uipanel(app.AxesTab);
            app.ScaleselectedaxesPanel.Title = 'Scale selected axes';
            app.WupButton = uibutton(app.ScaleselectedaxesPanel, 'push');
            app.WupButton.ButtonPushedFcn = createCallbackFcn(app, @WupButtonPushed, true);
            app.WupButton.Text = 'W ^';
            app.HupButton = uibutton(app.ScaleselectedaxesPanel, 'push');
            app.HupButton.ButtonPushedFcn = createCallbackFcn(app, @HupButtonPushed, true);
            app.HupButton.Text = 'H ^';
            app.WidthHeightupButton = uibutton(app.ScaleselectedaxesPanel, 'push');
            app.WidthHeightupButton.ButtonPushedFcn = createCallbackFcn(app, @WidthHeightupButtonPushed, true);
            app.WidthHeightupButton.Text = 'W & H ^';
            app.WdownButton = uibutton(app.ScaleselectedaxesPanel, 'push');
            app.WdownButton.ButtonPushedFcn = createCallbackFcn(app, @WdownButtonPushed, true);
            app.WdownButton.Text = 'W v';
            app.HdownButton = uibutton(app.ScaleselectedaxesPanel, 'push');
            app.HdownButton.ButtonPushedFcn = createCallbackFcn(app, @HdownButtonPushed, true);
            app.HdownButton.Text = 'H v';
            app.WidthHeightdownButton = uibutton(app.ScaleselectedaxesPanel, 'push');
            app.WidthHeightdownButton.ButtonPushedFcn = createCallbackFcn(app, @WidthHeightdownButtonPushed, true);
            app.WidthHeightdownButton.Text = 'W & H v';
            app.RotateselectedaxesPanel = uipanel(app.AxesTab);
            app.RotateselectedaxesPanel.Title = 'Rotate selected axes';
            app.RotateAxesEditField = uieditfield(app.RotateselectedaxesPanel, 'numeric');
            app.RotateAxesEditField.ValueChangedFcn = createCallbackFcn(app, @RotateAxesEditFieldValueChanged, true);
            app.degreesLabel = uilabel(app.RotateselectedaxesPanel);
            app.degreesLabel.Text = 'degrees';
            app.TextTab = uitab(app.TabGroup);
            app.TextTab.Title = 'Text';
            app.AddaxeslabelsPanel = uipanel(app.TextTab);
            app.AddaxeslabelsPanel.Title = 'Add axes labels';
            app.LabelEditField = uieditfield(app.AddaxeslabelsPanel, 'text');
            app.xEditFieldLabel_2 = uilabel(app.AddaxeslabelsPanel);
            app.xEditFieldLabel_2.Text = 'x';
            app.x_label = uieditfield(app.AddaxeslabelsPanel, 'numeric');
            app.yEditFieldLabel_2 = uilabel(app.AddaxeslabelsPanel);
            app.yEditFieldLabel_2.Text = 'y';
            app.y_label = uieditfield(app.AddaxeslabelsPanel, 'numeric');
            app.LabelEditFieldLabel = uilabel(app.AddaxeslabelsPanel);
            app.FontDropDownLabel = uilabel(app.AddaxeslabelsPanel);
            app.FontDropDownLabel.Text = 'Font';
            app.FontDropDown = uidropdown(app.AddaxeslabelsPanel);
            app.FontDropDown.Items = {'8', '9', '10', '11', '12', '13', '14', '15', '16', '18', '20', '22', '24', '26', '28', '30'};
            app.FontDropDown.Editable = 'on';
            app.FontDropDown.Value = '16';
            app.BoldCheckBox = uicheckbox(app.AddaxeslabelsPanel);
            app.BoldCheckBox.Text = '';
            app.BoldCheckBox.Value = true;
            app.BoldLabel = uilabel(app.AddaxeslabelsPanel);
            app.BoldLabel.Text = 'Bold';
            app.AddLabelButton = uibutton(app.AddaxeslabelsPanel, 'push');
            app.AddLabelButton.ButtonPushedFcn = createCallbackFcn(app, @AddLabelButtonPushed, true);
            app.AddLabelButton.Text = 'Add';
            app.EditLabelButton = uibutton(app.AddaxeslabelsPanel, 'push');
            app.EditLabelButton.ButtonPushedFcn = createCallbackFcn(app, @EditLabelButtonPushed, true);
            app.EditLabelButton.Text = 'Edit';
            app.DeleteLabelButton = uibutton(app.AddaxeslabelsPanel, 'push');
            app.DeleteLabelButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLabelButtonPushed, true);
            app.DeleteLabelButton.Text = 'Delete';
            app.UpdateLabelButton = uibutton(app.AddaxeslabelsPanel, 'push');
            app.UpdateLabelButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateLabelButtonPushed, true);
            app.UpdateLabelButton.Text = 'Update';
            app.moveLabelUpListButton = uibutton(app.AddaxeslabelsPanel, 'push');
            app.moveLabelUpListButton.Text = '^';
            app.moveLabelDownListButton = uibutton(app.AddaxeslabelsPanel, 'push');
            app.moveLabelDownListButton.Text = 'v';
            app.AutoAddLabelsButton = uibutton(app.AddaxeslabelsPanel, 'push');
            app.AutoAddLabelsButton.Text = 'Auto add';
            app.ListBoxLabel_2 = uilabel(app.AddaxeslabelsPanel);
            app.ListBoxLabel_2.Text = '';
            app.LabelListBox = uilistbox(app.AddaxeslabelsPanel);
            app.LabelListBox.Items = {};
            app.LabelListBox.Multiselect = 'on';
            app.LabelListBox.ValueChangedFcn = createCallbackFcn(app, @LabelListBoxValueChanged, true);
            app.LabelListBox.Value = {};
            app.ItalicsCheckBox = uicheckbox(app.AddaxeslabelsPanel);
            app.ItalicsCheckBox.Text = '';
            app.ItLabel = uilabel(app.AddaxeslabelsPanel);
            app.ItLabel.Text = 'It.';
            % Show the figure after all components are created
            app.design_figureUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = design_figure

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.design_figureUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.design_figureUIFigure)
        end
    end
end